classdef ESTOP_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ZEditField              matlab.ui.control.NumericEditField
        ZEditFieldLabel         matlab.ui.control.Label
        YEditField              matlab.ui.control.NumericEditField
        YEditFieldLabel         matlab.ui.control.Label
        XEditField              matlab.ui.control.NumericEditField
        XEditFieldLabel         matlab.ui.control.Label
        Q5Slider                matlab.ui.control.Slider
        Q5SliderLabel           matlab.ui.control.Label
        Q4Slider                matlab.ui.control.Slider
        Q4SliderLabel           matlab.ui.control.Label
        Q3Slider                matlab.ui.control.Slider
        Q3SliderLabel           matlab.ui.control.Label
        Q2Slider                matlab.ui.control.Slider
        Q2SliderLabel           matlab.ui.control.Label
        Q1Slider                matlab.ui.control.Slider
        Q1SliderLabel           matlab.ui.control.Label
        ButtonGroup             matlab.ui.container.ButtonGroup
        ManualModeButton        matlab.ui.control.ToggleButton
        LightCurtainDemoButton  matlab.ui.control.ToggleButton
        NormalModeButton        matlab.ui.control.ToggleButton
        TwistPullEStopButton    matlab.ui.control.Button
        Button_3                matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        Button                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        EmergencyPulled = 1;
    end
    
    properties (Access = public)
        E;
        SwitchStatus; % Description
        Switched;
        Q1;
        Q2;
        Q3;
        Q4;
        Q5;
        SliderChanged;
        FirstStart = 1;
        X;
        Y;
        Z;
        TextBoxChanged;
        LightCurtainDetect;
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.SwitchStatus = 0; % Description
        app.Switched = 0;
        app.Q1 = 0;
        app.Q2 = 0;
        app.Q3 = 0;
        app.Q4 = 0;
        app.Q5 = 0;
        app.SliderChanged = 0;
        app.X = 0;
        app.Y = 0;
        app.Z = 0;
        app.TextBoxChanged = 0;
        app.E = 2;
        app.LightCurtainDetect = 0;
        

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            disp ('Emergency Stop Button Pressed')
            app.E = 1;
            app.EmergencyPulled = 0;
            uiwait;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
            if (app.EmergencyPulled == 1 && app.E == 1)
             disp ('Reset Button Pressed')
             app.E = 2;
             
             
            end
            if (app.EmergencyPulled == 1)
                app.LightCurtainDetect = 0;
            end

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
             if app.E == 2
            
             %app.E = 0;
             uiresume;
             disp ('Start Button Pressed')
            end
        end

        % Button pushed function: TwistPullEStopButton
        function TwistPullEStopButtonPushed(app, event)
            if app.EmergencyPulled == 0
                disp ('Emergency Stop Button Unlatched')
            end
            app.EmergencyPulled = 1;
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if (app.NormalModeButton.Value == 1)
                app.SwitchStatus = 0;
            end
            if (app.LightCurtainDemoButton.Value == 1)
                app.SwitchStatus = 1;
            end
            if (app.ManualModeButton.Value == 1)
                app.SwitchStatus = 2;
            end
         
           % Switched = 1;
                    
            
        end

        % Button down function: ButtonGroup
        function ButtonGroupButtonDown(app, event)
            
        end

        % Value changed function: Q1Slider
        function Q1SliderValueChanged(app, event)
            value = app.Q1Slider.Value;
            app.Q1 = value;
            app.SliderChanged = 1;
        end

        % Value changed function: Q2Slider
        function Q2SliderValueChanged(app, event)
            value = app.Q2Slider.Value;
            app.Q2 = value;
            app.SliderChanged = 1;
        end

        % Value changed function: Q3Slider
        function Q3SliderValueChanged(app, event)
            value = app.Q3Slider.Value;
            app.Q3 = value;
            app.SliderChanged = 1;
        end

        % Value changed function: Q4Slider
        function Q4SliderValueChanged(app, event)
            value = app.Q4Slider.Value;
            app.Q4 = value;
            app.SliderChanged = 1;
        end

        % Value changed function: Q5Slider
        function Q5SliderValueChanged(app, event)
            value = app.Q5Slider.Value;
            app.Q5 = value;
            app.SliderChanged = 1;
        end

        % Callback function
        function STARTButtonPushed(app, event)
            if (app.FirstStart == 1)
                app.FirstStart = 0;
                uiresume;
            end
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            app.X = value;
            app.TextBoxChanged = 1;
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            app.Y = value;
            app.TextBoxChanged = 1;
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            value = app.ZEditField.Value;
            app.Z = value;
            app.TextBoxChanged = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'EmergencyStop.PNG';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'ResetButton.PNG';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'StartButton.PNG';
            app.Button_3.Text = '';
            app.TwistPullEStopButton = uibutton(app.UIFigure, 'push');
            app.TwistPullEStopButton.ButtonPushedFcn = createCallbackFcn(app, @TwistPullEStopButtonPushed, true);
            app.TwistPullEStopButton.Text = 'Twist/Pull E-Stop';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ButtonGroupButtonDown, true);
            app.NormalModeButton = uitogglebutton(app.ButtonGroup);
            app.NormalModeButton.Text = 'Normal Mode';
            app.NormalModeButton.Value = true;
            app.LightCurtainDemoButton = uitogglebutton(app.ButtonGroup);
            app.LightCurtainDemoButton.Text = 'Light Curtain Demo';
            app.ManualModeButton = uitogglebutton(app.ButtonGroup);
            app.ManualModeButton.Text = 'Manual Mode';
            app.Q1SliderLabel = uilabel(app.UIFigure);
            app.Q1SliderLabel.Text = 'Q1';
            app.Q1Slider = uislider(app.UIFigure);
            app.Q1Slider.Limits = [-180 180];
            app.Q1Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.Q1Slider.ValueChangedFcn = createCallbackFcn(app, @Q1SliderValueChanged, true);
            app.Q2SliderLabel = uilabel(app.UIFigure);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q2Slider = uislider(app.UIFigure);
            app.Q2Slider.Limits = [-180 180];
            app.Q2Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.Q2Slider.ValueChangedFcn = createCallbackFcn(app, @Q2SliderValueChanged, true);
            app.Q3SliderLabel = uilabel(app.UIFigure);
            app.Q3SliderLabel.Text = 'Q3';
            app.Q3Slider = uislider(app.UIFigure);
            app.Q3Slider.Limits = [-180 180];
            app.Q3Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.Q3Slider.ValueChangedFcn = createCallbackFcn(app, @Q3SliderValueChanged, true);
            app.Q4SliderLabel = uilabel(app.UIFigure);
            app.Q4SliderLabel.Text = 'Q4';
            app.Q4Slider = uislider(app.UIFigure);
            app.Q4Slider.Limits = [-180 180];
            app.Q4Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.Q4Slider.ValueChangedFcn = createCallbackFcn(app, @Q4SliderValueChanged, true);
            app.Q5SliderLabel = uilabel(app.UIFigure);
            app.Q5SliderLabel.Text = 'Q5';
            app.Q5Slider = uislider(app.UIFigure);
            app.Q5Slider.Limits = [-180 180];
            app.Q5Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.Q5Slider.ValueChangedFcn = createCallbackFcn(app, @Q5SliderValueChanged, true);
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESTOP_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end